classdef TableGraphEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        bgEditMode           matlab.ui.container.ButtonGroup
        Button3              matlab.ui.control.RadioButton
        Button2              matlab.ui.control.RadioButton
        rbModeXY             matlab.ui.control.RadioButton
        btnSave              matlab.ui.control.Button
        ddPlotType           matlab.ui.control.DropDown
        DropDownLabel        matlab.ui.control.Label
        ddVariable           matlab.ui.control.DropDown
        SelectvariableLabel  matlab.ui.control.Label
        tblData              matlab.ui.control.Table
        axPlot               matlab.ui.control.UIAxes
    end

    
properties (SetAccess = public)
    % === Данные ===
    originalData        % Исходные данные (полная матрица)
    currentData         % Текущие редактируемые данные
    selectedVariable    % Выбранная переменная из workspace
    editMode = 'XY'     % Режим редактирования: 'XY'
    currentPlotType = 'columns' % 'columns' или 'rows'
    
    % === Выбор части данных ===
    selectedColumns = [] % Индексы выбранных столбцов
    selectedRows = []    % Индексы выбранных строк
    
    % === Метки ===
    rowLabels = {}      % Метки строк
    columnLabels = {}   % Метки столбцов
    
    % === Перетаскивание ===
    selectedPoint = []  % Индекс выбранной точки [curveIndex, pointIndex]
    isDragging = false  % Флаг активного перетаскивания
    dragStartPosition = [] % Начальная позиция перетаскивания [x, y]
    
    % === Состояние приложения ===
    isUpdating = false  % Флаг обновления (предотвращает циклические обновления)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                 % STARTUPFCN Выполняется при запуске приложения
            %   Инициализирует dropdown переменных из workspace
            %   Вызывает helper функцию updateVariableDropdown из helpers/
            try
                % Проверить, что dropdown существует (используем isprop для App Designer)
                % В App Designer компоненты создаются как свойства, не как поля
                if ~isprop(app, 'ddVariable')
                    fprintf('Предупреждение: свойство ddVariable не найдено\n');
                    fprintf('Диагностика: проверяем доступные свойства...\n');
                    
                    % Диагностика: показать доступные свойства, содержащие 'Variable'
                    props = properties(app);
                    matchingProps = props(contains(props, 'Variable', 'IgnoreCase', true) | ...
                                        contains(props, 'dd', 'IgnoreCase', true) | ...
                                        contains(props, 'Dropdown', 'IgnoreCase', true));
                    if ~isempty(matchingProps)
                        fprintf('Найдены похожие свойства: %s\n', strjoin(matchingProps, ', '));
                    else
                        fprintf('Похожие свойства не найдены. Убедитесь, что компонент создан в Design View\n');
                    end
                    return;
                end
                
                % Проверить, что объект валиден
                if ~isvalid(app.ddVariable)
                    fprintf('Предупреждение: ddVariable не валиден\n');
                    return;
                end
                
                % Назначить callback программно (всегда, для надежности)
                fprintf('Назначение callback ddVariableValueChanged...\n');
                try
                    % Используем createCallbackFcn для правильной работы с private методами
                    app.ddVariable.ValueChangedFcn = createCallbackFcn(app, @ddVariableValueChanged, true);
                    fprintf('✓ Callback назначен через createCallbackFcn\n');
                catch ME
                    fprintf('✗ Ошибка назначения callback: %s\n', ME.message);
                    fprintf('Стек ошибки:\n');
                    for i = 1:length(ME.stack)
                        fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                    end
                end
                
                % Проверить доступность helper функции и добавить путь, если нужно
                scriptPath = fileparts(mfilename('fullpath'));
                helpersPath = fullfile(scriptPath, 'helpers');
                
                if exist(helpersPath, 'dir') && ~contains(path, helpersPath)
                    addpath(helpersPath);
                    fprintf('✓ Путь helpers/ добавлен\n');
                end
                
                % Обновить список переменных
                if exist('updateVariableDropdown', 'file') == 2
                    updateVariableDropdown(app);
                else
                    fprintf('⚠ Функция updateVariableDropdown не найдена в path\n');
                end
                
                fprintf('✓ Инициализация завершена успешно\n');
                
            catch ME
                fprintf('Ошибка в startupFcn: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка инициализации: %s', ME.message), ...
                        'Ошибка запуска', ...
                        'Icon', 'error');
                end
            end
            
        end

        % Value changed function: ddVariable
        function ddVariableValueChanged(app, event)
           % DDVARIABLEVALUECHANGED Обработчик выбора переменной
            %   Загружает данные из workspace при выборе переменной
            %   Вызывает helper функцию loadVariableFromWorkspace из helpers/
            
            fprintf('ddVariableValueChanged вызван\n');  % Отладочный вывод
            
            try
                % Получить выбранную переменную
                % Если используется ItemsData, то Value будет содержать значение из ItemsData
                % Иначе - строку из Items
                varName = app.ddVariable.Value;
                fprintf('Выбранное значение: %s (тип: %s)\n', ...
                    mat2str(varName), class(varName));
                
                % Преобразовать в строку, если это не строка
                if isnumeric(varName)
                    varName = '';
                elseif ischar(varName)
                    varName = varName;
                elseif isstring(varName)
                    varName = char(varName);
                else
                    varName = '';
                end
                
                fprintf('Обработанное значение: "%s"\n', varName);
                
                % Проверить, что выбрана валидная переменная
                if isempty(varName) || strcmp(varName, 'Select variable...') || ...
                   strcmp(varName, 'Нет числовых переменных') || strcmp(varName, '')
                    fprintf('Пропуск: значение не валидно\n');
                    return;
                end
                
                fprintf('Загрузка переменной: %s\n', varName);
                
                % Добавить путь к helpers, если нужно
                scriptPath = fileparts(mfilename('fullpath'));
                helpersPath = fullfile(scriptPath, 'helpers');
                if exist(helpersPath, 'dir') && ~contains(path, helpersPath)
                    addpath(helpersPath);
                    fprintf('✓ Путь helpers/ добавлен: %s\n', helpersPath);
                end
                
                % Проверить доступность helper функции
                funcExists = exist('loadVariableFromWorkspace', 'file');
                fprintf('Проверка функции loadVariableFromWorkspace: %d (2=файл, 5=встроенная)\n', funcExists);
                
                if funcExists == 2
                    fprintf('Вызов loadVariableFromWorkspace...\n');
                    try
                        % Загрузить переменную (helper функция сделает валидацию)
                        loadVariableFromWorkspace(app, varName);
                        fprintf('✓ loadVariableFromWorkspace завершен\n');
                    catch ME
                        fprintf('✗ Ошибка в loadVariableFromWorkspace: %s\n', ME.message);
                        fprintf('Стек ошибки:\n');
                        for i = 1:length(ME.stack)
                            fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                        end
                        rethrow(ME);
                    end
                else
                    fprintf('✗ Функция loadVariableFromWorkspace НЕ найдена в path!\n');
                    fprintf('  Путь helpers: %s\n', helpersPath);
                    fprintf('  Директория существует: %d\n', exist(helpersPath, 'dir'));
                    if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                        uialert(app.UIFigure, ...
                            sprintf('Helper функции не найдены. Путь: %s', helpersPath), ...
                            'Ошибка загрузки', ...
                            'Icon', 'error');
                    end
                end
                
            catch ME
                fprintf('Ошибка в ddVariableValueChanged: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка загрузки данных: %s', ME.message), ...
                        'Ошибка загрузки', ...
                        'Icon', 'error');
                end
            end

        end

        % Value changed function: ddPlotType
        function ddPlotTypeValueChanged(app, event)
             % DDPLOTTYPEVALUECHANGED Обработчик изменения типа графика
            %   Обновляет режим построения графика и перестраивает график
            %   Также обновляет таблицу для корректного отображения меток
            %
            %   ВАЖНО: Этот callback должен быть назначен в Design View:
            %   1. Выберите компонент ddPlotType в Design View
            %   2. В Property Inspector найдите "ValueChangedFcn"
            %   3. Установите: @app.ddPlotTypeValueChanged
            %   4. Сохраните файл
            
            fprintf('ddPlotTypeValueChanged вызван\n');  % Отладочный вывод
            
            try
                % Получить выбранное значение
                selectedValue = app.ddPlotType.Value;
                fprintf('Выбранное значение: %s (тип: %s)\n', ...
                    mat2str(selectedValue), class(selectedValue));
                
                % Преобразовать значение dropdown в режим ('columns' или 'rows')
                % Предполагаем, что Items содержат: {'By Columns', 'By Rows'}
                % или {'columns', 'rows'}
                if ischar(selectedValue) || isstring(selectedValue)
                    selectedValue = char(selectedValue);
                    if contains(selectedValue, 'Column', 'IgnoreCase', true) || ...
                       strcmp(selectedValue, 'columns')
                        plotType = 'columns';
                    elseif contains(selectedValue, 'Row', 'IgnoreCase', true) || ...
                           strcmp(selectedValue, 'rows')
                        plotType = 'rows';
                    else
                        plotType = 'columns';  % По умолчанию
                    end
                else
                    plotType = 'columns';  % По умолчанию
                end
                
                fprintf('Установка режима: %s\n', plotType);
                
                % Обновить тип графика (безопасно)
                if isprop(app, 'currentPlotType')
                    try
                        app.currentPlotType = plotType;
                        fprintf('✓ currentPlotType установлен: %s\n', plotType);
                    catch ME
                        fprintf('Предупреждение: не удалось установить currentPlotType: %s\n', ME.message);
                        % Попробовать через UserData
                        if ~isfield(app.UIFigure.UserData, 'appData')
                            app.UIFigure.UserData.appData = struct();
                        end
                        app.UIFigure.UserData.appData.currentPlotType = plotType;
                        fprintf('currentPlotType сохранен в UserData\n');
                    end
                else
                    % Сохранить в UserData, если свойство не существует
                    if ~isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData = struct();
                    end
                    app.UIFigure.UserData.appData.currentPlotType = plotType;
                    fprintf('currentPlotType сохранен в UserData (свойство не существует)\n');
                end
                
                % Обновить таблицу с новыми метками (важно для корректного отображения)
                if exist('updateTableWithData', 'file') == 2
                    fprintf('Обновление таблицы...\n');
                    updateTableWithData(app);
                    fprintf('✓ Таблица обновлена\n');
                else
                    fprintf('⚠ Функция updateTableWithData не найдена\n');
                end
                
                % Обновить график (вызов helper функции)
                if exist('updateGraph', 'file') == 2
                    fprintf('Обновление графика...\n');
                    updateGraph(app);
                    fprintf('✓ График обновлен\n');
                else
                    fprintf('⚠ Функция updateGraph не найдена\n');
                end
                
            catch ME
                fprintf('Ошибка в ddPlotTypeValueChanged: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка изменения режима графика: %s', ME.message), ...
                        'Ошибка', ...
                        'Icon', 'error');
                end
            end
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
        % BTNSAVEBUTTONPUSHED Обработчик нажатия кнопки сохранения
            %   Сохраняет данные в workspace с подтверждением
            %   Поддерживает как прямые переменные, так и переменные из структур
            %   Вызывает helper функцию saveVariableToWorkspace из helpers/
            %
            %   ВАЖНО: Этот callback должен быть назначен в Design View:
            %   1. Выберите компонент btnSave в Design View
            %   2. В Property Inspector найдите "ButtonPushedFcn"
            %   3. Установите: @app.btnSaveButtonPushed
            %   4. Сохраните файл
            
            fprintf('btnSaveButtonPushed вызван\n');  % Отладочный вывод
            
            try
                % Получить данные для сохранения (безопасно)
                currentData = [];
                selectedVariable = '';
                
                % Попробовать получить currentData
                if isprop(app, 'currentData')
                    currentData = app.currentData;
                elseif isfield(app.UIFigure.UserData, 'appData') && ...
                       isfield(app.UIFigure.UserData.appData, 'currentData')
                    currentData = app.UIFigure.UserData.appData.currentData;
                end
                
                % Попробовать получить selectedVariable
                if isprop(app, 'selectedVariable')
                    selectedVariable = app.selectedVariable;
                elseif isfield(app.UIFigure.UserData, 'appData') && ...
                       isfield(app.UIFigure.UserData.appData, 'selectedVariable')
                    selectedVariable = app.UIFigure.UserData.appData.selectedVariable;
                end
                
                % Проверить, что данные загружены
                if isempty(currentData) || isempty(selectedVariable)
                    uialert(app.UIFigure, ...
                        'Нет данных для сохранения. Сначала загрузите переменную.', ...
                        'Ошибка сохранения', ...
                        'Icon', 'warning');
                    return;
                end
                
                % Показать диалог подтверждения
                message = sprintf('Перезаписать переменную "%s" в workspace?', ...
                    selectedVariable);
                
                selection = uiconfirm(app.UIFigure, ...
                    message, ...
                    'Подтверждение сохранения', ...
                    'Options', {'Да', 'Нет'}, ...
                    'DefaultOption', 'Нет', ...
                    'CancelOption', 'Нет', ...
                    'Icon', 'question');
                
                % Проверить ответ
                if strcmp(selection, 'Да')
                    % Сохранить данные через helper функцию
                    % (поддерживает как прямые переменные, так и структуры)
                    saveVariableToWorkspace(app, selectedVariable, currentData);
                    
                    % Обновить originalData
                    if isprop(app, 'originalData')
                        app.originalData = currentData;
                    elseif isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData.originalData = currentData;
                    end
                    
                    % Показать сообщение об успехе
                    uialert(app.UIFigure, ...
                        sprintf('Данные успешно сохранены в переменную "%s"', ...
                            selectedVariable), ...
                        'Сохранение завершено', ...
                        'Icon', 'success');
                    
                    fprintf('✓ Данные сохранены успешно\n');
                else
                    fprintf('Сохранение отменено пользователем\n');
                end
                
            catch ME
                fprintf('Ошибка в btnSaveButtonPushed: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка сохранения: %s', ME.message), ...
                        'Ошибка сохранения', ...
                        'Icon', 'error');
                end
            end         
        end

        % Cell edit callback: tblData
        function tblDataCellEdit(app, event)
   % TBLDATACELLEDIT Callback редактирования ячейки таблицы
    %   Обновляет данные и график при изменении таблицы
    
    try
        % Предотвратить циклические обновления
        if app.isUpdating
            return;
        end
        
        app.isUpdating = true;
        
        % Получить новые данные из таблицы
        newData = app.tblData.Data;
        
        % Проверить, что данные числовые
        if ~isnumeric(newData)
            uialert(app.UIFigure, 'Данные должны быть числовыми', 'Ошибка');
            app.tblData.Data = app.currentData; % Восстановить старые данные
            app.isUpdating = false;
            return;
        end
        
        % Обновить текущие данные
        app.currentData = newData;
        
        % Обновить график (вызов helper функции)
        updateGraph(app);
        
        app.isUpdating = false;
        
    catch ME
        app.isUpdating = false;
        uialert(app.UIFigure, sprintf('Ошибка редактирования: %s', ME.message), 'Ошибка');
        % Восстановить старые данные
        app.tblData.Data = app.currentData;
    end
        end

        % Cell selection callback: tblData
        function tblDataCellSelection(app, event)
           % TBLDATASELECTIONCHANGED Обработчик изменения выделения в таблице
            %   Обновляет выделенные столбцы/строки и перерисовывает график
            %   Вызывает helper функцию updateSelection из helpers/
            %
            %   ВАЖНО: Этот callback должен быть назначен в Design View:
            %   1. Выберите компонент tblData в Design View
            %   2. В Property Inspector найдите "SelectionChangedFcn"
            %   3. Установите: @app.tblDataSelectionChanged
            %   4. Сохраните файл
            %
            %   Примечание: uitable.Selection содержит массив [row, col] для каждой
            %   выделенной ячейки. Функция updateSelection извлекает уникальные
            %   столбцы/строки и исключает столбец/строку X (используемые для координат)
            
            fprintf('tblDataSelectionChanged вызван\n');  % Отладочный вывод
            
            try
                % Получить выделение из таблицы
                if ~isprop(app, 'tblData') || ~isvalid(app.tblData)
                    fprintf('⚠ tblData не найден или не валиден\n');
                    return;
                end
                
                selection = app.tblData.Selection;
                fprintf('Выделение получено: %s\n', mat2str(selection));
                
                % Обновить выделение через helper функцию
                % (извлекает уникальные столбцы/строки и исключает столбец/строку X)
                if exist('updateSelection', 'file') == 2
                    updateSelection(app, selection);
                    fprintf('✓ Выделение обновлено\n');
                else
                    fprintf('⚠ Функция updateSelection не найдена\n');
                    return;
                end
                
                % Обновить график (только для выделенных столбцов/строк)
                if exist('updateGraph', 'file') == 2
                    fprintf('Обновление графика с учетом выделения...\n');
                    updateGraph(app);
                    fprintf('✓ График обновлен\n');
                else
                    fprintf('⚠ Функция updateGraph не найдена\n');
                end
                
            catch ME
                fprintf('Ошибка в tblDataSelectionChanged: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                % Не показывать ошибку пользователю для выделения (не критичная операция)
                % Можно раскомментировать, если нужно показывать ошибки:
                % if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                %     uialert(app.UIFigure, ...
                %         sprintf('Ошибка обработки выделения: %s', ME.message), ...
                %         'Ошибка выделения', ...
                %         'Icon', 'warning');
                % end
            end
        end

        % Button down function: axPlot
        function axPlotButtonDown(app, event)

            % AXPLOTBUTTONDOWN Обработчик клика на графике
            %   Начинает перетаскивание точки при клике на графике
            %   Вызывает helper функцию findClosestPoint из helpers/
            %
            %   ВАЖНО: Этот callback должен быть назначен в Design View:
            %   1. Выберите компонент axPlot в Design View
            %   2. В Property Inspector найдите "ButtonDownFcn"
            %   3. Установите: @app.axPlotButtonDown
            %   4. Сохраните файл
            %
            %   Примечание: Использует CurrentPoint из axes для получения
            %   точных координат клика в координатах данных.
            %   CurrentPoint более надежен, чем IntersectionPoint, особенно
            %   при клике на маркерах точек.
            
            fprintf('axPlotButtonDown вызван\n');  % Отладочный вывод
            
            try
                % Проверить, что график существует и валиден
                if ~isprop(app, 'axPlot') || ~isvalid(app.axPlot)
                    fprintf('⚠ axPlotButtonDown: axPlot не найден или не валиден\n');
                    return;
                end
                
                % Получить координаты клика в координатах данных
                % ВАЖНО: Используем CurrentPoint вместо IntersectionPoint, так как
                % IntersectionPoint может быть неточным при клике на маркере точки.
                % CurrentPoint всегда возвращает точные координаты мыши в координатах данных.
                cp = app.axPlot.CurrentPoint;
                clickPos = cp(1, 1:2);
                
                % Альтернативно, можно попробовать использовать IntersectionPoint,
                % но только если он доступен и валиден
                % if isprop(event, 'IntersectionPoint') && ~isempty(event.IntersectionPoint)
                %     intersectionPos = event.IntersectionPoint(1:2);
                %     % Проверить, что IntersectionPoint не слишком далеко от CurrentPoint
                %     if norm(intersectionPos - clickPos) < 0.01 * norm(clickPos)
                %         clickPos = intersectionPos;
                %     end
                % end
                
                fprintf('Координаты клика (CurrentPoint): [%.4f, %.4f]\n', clickPos(1), clickPos(2));
                
                % Найти ближайшую точку через helper функцию
                if exist('findClosestPoint', 'file') == 2
                    pointIndex = findClosestPoint(app, clickPos);
                else
                    fprintf('⚠ Функция findClosestPoint не найдена\n');
                    return;
                end
                
                if isempty(pointIndex)
                    fprintf('⚠ Точка не найдена (клик слишком далеко от точек)\n');
                    return;
                end
                
                fprintf('✓ Найдена точка: кривая %d, точка %d\n', pointIndex(1), pointIndex(2));
                
                % Сохранить индекс точки и начать перетаскивание (безопасно)
                if isprop(app, 'selectedPoint')
                    try
                        app.selectedPoint = pointIndex;
                    catch
                        if ~isfield(app.UIFigure.UserData, 'appData')
                            app.UIFigure.UserData.appData = struct();
                        end
                        app.UIFigure.UserData.appData.selectedPoint = pointIndex;
                    end
                else
                    if ~isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData = struct();
                    end
                    app.UIFigure.UserData.appData.selectedPoint = pointIndex;
                end
                
                if isprop(app, 'isDragging')
                    try
                        app.isDragging = true;
                    catch
                        if ~isfield(app.UIFigure.UserData, 'appData')
                            app.UIFigure.UserData.appData = struct();
                        end
                        app.UIFigure.UserData.appData.isDragging = true;
                    end
                else
                    if ~isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData = struct();
                    end
                    app.UIFigure.UserData.appData.isDragging = true;
                end
                
                if isprop(app, 'dragStartPosition')
                    try
                        app.dragStartPosition = clickPos;
                    catch
                        if ~isfield(app.UIFigure.UserData, 'appData')
                            app.UIFigure.UserData.appData = struct();
                        end
                        app.UIFigure.UserData.appData.dragStartPosition = clickPos;
                    end
                else
                    if ~isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData = struct();
                    end
                    app.UIFigure.UserData.appData.dragStartPosition = clickPos;
                end
                
                % Установить callbacks для движения мыши
                % ВАЖНО: В MATLAB App Designer WindowButtonMotionFcn может не работать
                % надежно. Используем комбинированный подход:
                % 1. WindowButtonMotionFcn (если работает)
                % 2. Таймер для периодической проверки движения мыши (fallback)
                
                % Сохранить ссылку на app в UserData для доступа из callbacks
                app.UIFigure.UserData.dragApp = app;
                
                % Установить WindowButtonMotionFcn (основной механизм)
                % Используем прямые вызовы функций из helpers/
                app.UIFigure.WindowButtonMotionFcn = @(src,evt) dragPoint(app, src, evt);
                app.UIFigure.WindowButtonUpFcn = @(src,evt) stopDrag(app, src, evt);
                
                % Создать таймер для отслеживания движения мыши (fallback механизм)
                % Таймер будет проверять позицию мыши каждые 0.05 секунды
                if isprop(app, 'dragTimer')
                    try
                        if isvalid(app.dragTimer)
                            stop(app.dragTimer);
                            delete(app.dragTimer);
                        end
                    catch
                    end
                end
                
                % Создать новый таймер
                % ВАЖНО: Сохраняем app в UserData таймера для доступа из callback
                dragTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.05, ...  % Проверка каждые 50 мс
                    'TimerFcn', @(t,~) checkMouseMovement(app, t), ...
                    'BusyMode', 'drop', ...
                    'TasksToExecute', Inf);
                
                % Сохранить app в UserData таймера для доступа из callback
                dragTimer.UserData.dragApp = app;
                
                % Сохранить таймер в app
                if isprop(app, 'dragTimer')
                    try
                        app.dragTimer = dragTimer;
                    catch
                        app.UIFigure.UserData.dragTimer = dragTimer;
                    end
                else
                    app.UIFigure.UserData.dragTimer = dragTimer;
                end
                
                % Сохранить предыдущую позицию мыши для отслеживания движения
                % В MacOS координаты могут работать по-другому, учитываем это
                currentMousePos = get(0, 'PointerLocation');
                if isprop(app, 'lastMousePosition')
                    try
                        app.lastMousePosition = currentMousePos;
                    catch
                        app.UIFigure.UserData.lastMousePosition = currentMousePos;
                    end
                else
                    app.UIFigure.UserData.lastMousePosition = currentMousePos;
                end
                
                % В MacOS также сохраняем начальные координаты данных
                if ismac && isprop(app, 'axPlot') && isvalid(app.axPlot)
                    try
                        cp = app.axPlot.CurrentPoint;
                        initialDataPos = cp(1, 1:2);
                        app.UIFigure.UserData.lastDataPosition = initialDataPos;
                        fprintf('✓ Начальные координаты данных для MacOS: [%.4f, %.4f]\n', ...
                            initialDataPos(1), initialDataPos(2));
                    catch
                        % Игнорировать ошибки
                    end
                end
                
                % Запустить таймер
                start(dragTimer);
                
                % Проверить, что таймер действительно запущен
                if strcmp(get(dragTimer, 'Running'), 'on')
                    fprintf('✓ Таймер запущен успешно (Running=on)\n');
                else
                    fprintf('⚠ Таймер НЕ запущен! (Running=%s)\n', get(dragTimer, 'Running'));
                end
                
                fprintf('✓ Callbacks установлены + таймер запущен (fallback механизм)\n');
                
                fprintf('✓ Callbacks установлены\n');
                
                % Проверить, что callbacks установлены
                if ~isempty(app.UIFigure.WindowButtonMotionFcn)
                    fprintf('✓ WindowButtonMotionFcn установлен\n');
                else
                    fprintf('⚠ WindowButtonMotionFcn НЕ установлен!\n');
                end
                if ~isempty(app.UIFigure.WindowButtonUpFcn)
                    fprintf('✓ WindowButtonUpFcn установлен\n');
                else
                    fprintf('⚠ WindowButtonUpFcn НЕ установлен!\n');
                end
                
                % Добавить флаг, что перетаскивание действительно началось
                % (для проверки, что мышь двигалась)
                if isprop(app, 'dragStartPosition')
                    try
                        app.dragStartPosition = clickPos;
                    catch
                        if ~isfield(app.UIFigure.UserData, 'appData')
                            app.UIFigure.UserData.appData = struct();
                        end
                        app.UIFigure.UserData.appData.dragStartPosition = clickPos;
                    end
                else
                    if ~isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData = struct();
                    end
                    app.UIFigure.UserData.appData.dragStartPosition = clickPos;
                end
                
                % Флаг, что мышь двигалась (для отличия клика от перетаскивания)
                if isprop(app, 'hasMoved')
                    try
                        app.hasMoved = false;
                    catch
                        if ~isfield(app.UIFigure.UserData, 'appData')
                            app.UIFigure.UserData.appData = struct();
                        end
                        app.UIFigure.UserData.appData.hasMoved = false;
                    end
                else
                    if ~isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData = struct();
                    end
                    app.UIFigure.UserData.appData.hasMoved = false;
                end
                
                fprintf('✓ Перетаскивание начато (callbacks установлены)\n');
                
            catch ME
                fprintf('Ошибка в axPlotButtonDown: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка начала перетаскивания: %s', ME.message), ...
                        'Ошибка перетаскивания', ...
                        'Icon', 'error');
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Table-Graph Editor';
            app.UIFigure.Tag = 'Table-Graph Editor';
            app.axPlot = uiaxes(app.UIFigure);
            title(app.axPlot, 'Title')
            xlabel(app.axPlot, 'X')
            ylabel(app.axPlot, 'Y')
            zlabel(app.axPlot, 'Z')
            app.axPlot.ButtonDownFcn = createCallbackFcn(app, @axPlotButtonDown, true);
            app.tblData = uitable(app.UIFigure);
            app.tblData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tblData.RowName = {};
            app.tblData.ColumnEditable = true;
            app.tblData.CellEditCallback = createCallbackFcn(app, @tblDataCellEdit, true);
            app.tblData.CellSelectionCallback = createCallbackFcn(app, @tblDataCellSelection, true);
            app.SelectvariableLabel = uilabel(app.UIFigure);
            app.SelectvariableLabel.Text = 'Select variable...';
            app.ddVariable = uidropdown(app.UIFigure);
            app.ddVariable.Items = {'Select variable...'};
            app.ddVariable.ValueChangedFcn = createCallbackFcn(app, @ddVariableValueChanged, true);
            app.ddVariable.Value = 'Select variable...';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.ddPlotType = uidropdown(app.UIFigure);
            app.ddPlotType.Items = {'By Columns', 'By Rows'};
            app.ddPlotType.ValueChangedFcn = createCallbackFcn(app, @ddPlotTypeValueChanged, true);
            app.ddPlotType.Value = 'By Columns';
            app.btnSave = uibutton(app.UIFigure, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Text = 'Save to Workspace';
            app.bgEditMode = uibuttongroup(app.UIFigure);
            app.bgEditMode.Title = 'Edit Mode';
            app.rbModeXY = uiradiobutton(app.bgEditMode);
            app.rbModeXY.Text = 'XY';
            app.rbModeXY.Value = true;
            app.Button2 = uiradiobutton(app.bgEditMode);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.bgEditMode);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableGraphEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end